classdef wp_publisher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WordPressPublisher        matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        PublishpostTab            matlab.ui.container.Tab
        EnableDownLoadCheckBox    matlab.ui.control.CheckBox
        PublishdraftButton        matlab.ui.control.Button
        AppLabel                  matlab.ui.control.Label
        SelectLiveScriptDropDown  matlab.ui.control.DropDown
        SelectLiveScript          matlab.ui.control.Label
        SettingsTab               matlab.ui.container.Tab
        OutputpathLabel           matlab.ui.control.Label
        PathField                 matlab.ui.control.EditField
        WpAuthButtonGroup         matlab.ui.container.ButtonGroup
        BasicButton               matlab.ui.control.RadioButton
        JwtButton                 matlab.ui.control.RadioButton
        BlogInfoActionLabel       matlab.ui.control.Label
        WordPresspasswordLabel    matlab.ui.control.Label
        PasswordField             matlab.ui.control.EditField
        WordPressusernameLabel    matlab.ui.control.Label
        UsernameField             matlab.ui.control.EditField
        WordPressURLLabel         matlab.ui.control.Label
        BlogSiteEditField         matlab.ui.control.EditField
        SaveButton                matlab.ui.control.Button
        ErrorLabel                matlab.ui.control.Label
    end

    % Copyright 2021 - 2021 The MathWorks, Inc.
    properties (Access = private)
        Property % Description
    end

    methods (Access = private)

        function customizedPath = checkPath(app)
            appDir = wp_publisher.getAppDirectory();
            customizedPath = "";
            if isfile(appDir + "bloginfo.mat")
                load(appDir + "bloginfo.mat", 'toolpath');
                if ~isempty(toolpath)
                    if (exist(toolpath, 'dir') ~= 0)
                        if toolpath(end) == filesep
                            customizedPath = string(toolpath);
                        else
                            customizedPath = string(toolpath) + filesep;
                        end
                    end
                end
            end
        end

        function mlxDropdown(app, file)
            mlx = dir("*.mlx");
            if ~isempty(mlx)
                if ~strcmp(file, "all")
                    if isfile(file)
                        mlx = mlx(~startsWith({mlx.name}, file));
                        mlx = [dir(file); mlx];
                        app.SelectLiveScriptDropDown.Items = {mlx.name};
                    else
                        fprintf('Sorry, the file you input is not found in the folder \n');
                        app.ErrorLabel.Text = "File not found, please select from dropdown";
                        app.SelectLiveScriptDropDown.Items = {mlx.name};
                    end
                else
                    app.SelectLiveScriptDropDown.Items = {mlx.name};
                end
                mlxfile = app.SelectLiveScriptDropDown.Value;
                mlxname = erase(mlxfile, ".mlx");
                customizedPath = app.checkPath();
                newpath = customizedPath + mlxname;
                if exist(newpath, 'dir') && isfile(newpath + "/post_info.mat")
                    load(fullfile(newpath,"/post_info.mat"));
                    app.AppLabel.Text = "Edit existing blog article";
                    delete(newpath + "/*.html");
                else
                    app.AppLabel.Text = "Post new blog article";
                end

            else
                fprintf('Sorry, there are no live scripts found in the folder \n');
                app.ErrorLabel.Text = "Sorry, there are no live scripts found in the folder";
            end
        end

        function mlxTohtml(app)
            appDir = wp_publisher.getAppDirectory();
            if isfile(appDir + "bloginfo.mat")
                load(appDir + "bloginfo.mat", 'toolpath');
            end
            mlxname = app.SelectLiveScriptDropDown.Value;
            d = dir(mlxname);
            dname = d.name;
            dfolder = d.folder;

            if ~isempty(d)
                timestamp = datetime(d.datenum,"ConvertFrom","datenum",'Format','yyyy-MM-dd''T''HHmmss');
                ts = convertCharsToStrings(datestr(timestamp, 'mm-dd-yy'));
                tmpfile = char(convertCharsToStrings(d.name) + '-' + ts + '.html');
                mlxname = erase(d.name, ".mlx");
                if ~isempty(toolpath)
                    if (exist(toolpath, 'dir') ~= 0)
                        cd(toolpath)
                        if ~exist(fullfile(cd, mlxname), 'dir')
                            mkdir(mlxname);
                        end
                        customizedPath = app.checkPath();
                        mlxfile = string(dfolder) + filesep + string(dname);
                        convertScript(convertStringsToChars(mlxfile), convertStringsToChars(customizedPath), mlxname, tmpfile);

                    else
                        cd(dfolder)
                        if ~exist(fullfile(cd, mlxname), 'dir')
                            mkdir(mlxname);
                        end
                        convertScript(which(dname), dfolder, mlxname, tmpfile);
                    end
                else
                    cd(dfolder)
                    if ~exist(fullfile(cd, mlxname), 'dir')
                        mkdir(mlxname);
                    end
                    convertScript(which(dname), dfolder, mlxname, tmpfile);
                end
                fprintf('Live script is converted into HTML. \n');

                cd(mlxname)

            else
                fprintf('Sorry, there is no live script found in the folder \n');
                app.ErrorLabel.Text = "Sorry, there is no live script found in the folder";
            end
        end

        function title = getPostTitle(app, html)
            tree = htmlTree(fileread(html));
            postTitle = extractHTMLText(findElement(tree,"H1"));
            if ~isempty(postTitle)
                title = postTitle;
                fprintf('Article - %s is ready to be published to your blog \n', postTitle);
            else
                title = "Post title placeholder";
                app.ErrorLabel.Text = "Article title is empty";
                fprintf('Article is ready to be published to your blog, please don\''t forget to change title of your article. \n');
            end
        end

        function removeMlxTitle(app, html)
            tree = htmlTree(fileread(html));
            postTitle = extractHTMLText(findElement(tree,"H1"));
            if ~isempty(postTitle)
                str = convertCharsToStrings(fileread(html));
                treeStr = regexprep(str,'<h1.*?<\/h1>', '');
                fileid = fopen('article_body.html', 'wb');
                fwrite(fileid, treeStr, 'char');
                fclose(fileid);
            else
                copyfile(html, "article_body.html");
            end
        end

        function getAllLatex(app, html)
            str = convertCharsToStrings(fileread(html));
            treeStr = regexp(str,'((?<=<body>).*(?=<\/body>))','match');
            spanTrees = regexp(treeStr,'<span[^>]*>(.*?)</span>','match');
            if ~isempty(spanTrees)
                O = containers.Map({'span'}, {'newSpan'});
                for index = 1:length(spanTrees)
                    if contains(spanTrees(index), "texencoding") == 1
                        latexSpan = htmlTree(spanTrees(index));
                        latex = "$ " + getAttribute(findElement(latexSpan, "span"), "texencoding") + " $";
                        newSpan = regexprep(string(spanTrees(index)), '<img .*?>', 'placeholder');
                        sp = strrep(newSpan, 'placeholder', latex);
                        O(string(spanTrees(index))) = string(sp);
                    end
                end
                k = keys(O);
                val = values(O);
                for i = 1:(length(O)-1)
                    str = strrep(str, string(k{i}), string(val{i}));
                end
            end


            fileid = fopen('article_body.html', 'wb');
            fwrite(fileid, str, 'char');
            fclose(fileid);

            fprintf('LaTeX convert completed. \n');

        end

        function getAllEquation(app, html)
            str = convertCharsToStrings(fileread(html));
            treeStr = regexp(str,'((?<=<body>).*(?=<\/body>))','match');
            spanTrees = regexp(treeStr,'<span[^>]*>(.*?)</span>','match');
            if ~isempty(spanTrees)
                O = containers.Map({'span'}, {'newSpan'});
                for index = 1:length(spanTrees)
                    if contains(spanTrees(index), "mathmlencoding") == 1
                        newSpan = regexprep(string(spanTrees(index)), '<img .*?>', "equation_placeholder");
                        O(string(spanTrees(index))) = string(newSpan);
                    end
                end
                k = keys(O);
                val = values(O);
                for i = 1:(length(O)-1)
                    str = strrep(str, string(k{i}), string(val{i}));
                end
            end

            fileid = fopen('article_body.html', 'wb');
            fwrite(fileid, str, 'char');
            fclose(fileid);

            fprintf('Equations convert completed. \n');

        end

        function replaceEquation(app, origHtml, convertedHtml)
            origStr = convertCharsToStrings(fileread(origHtml));
            origTreeStr = regexp(origStr,'((?<=<body>).*(?=<\/body>))','match');
            origSpanTrees = regexp(origTreeStr,'<span[^>]*>(.*?)</span>','match');

            if ~isempty(origSpanTrees)
                P = containers.Map({'span'}, {'newSpan'});
                for i = 1:length(origSpanTrees)
                    if contains(origSpanTrees(i), "mathmlencoding") == 1
                        latexSpan = htmlTree(origSpanTrees(i));
                        latex = getAttribute(findElement(latexSpan, "span"), "mathmlencoding");
                        newSpan = regexprep(string(origSpanTrees(i)), '<img .*?>', latex);
                        P(string(origSpanTrees(i))) = string(newSpan);
                    end
                end
                val = values(P);
            end

            convertedStr = convertCharsToStrings(fileread(convertedHtml));
            convertedSpanTrees = regexp(convertedStr,'<span[^>]*>(.*?)</span>','match');
            if ~isempty(convertedSpanTrees)
                Q = containers.Map({'span'}, {'newSpan'});
                for i = 1:length(convertedSpanTrees)
                    if contains(convertedSpanTrees(i), "mathmlencoding") == 1
                        Q(string(convertedSpanTrees(i))) = "";
                    end
                end
                key = keys(Q);
                for i = 1:(length(Q)-1)
                    convertedStr = strrep(convertedStr, string(key{i}), string(val{i}));
                end
            end

            fileid = fopen('article_body.html', 'wb');
            fwrite(fileid, convertedStr, 'char');
            fclose(fileid);
            fprintf('Equations replacement completed. \n');
        end

        function getImgToFile(app, mlxname, html, blog_url)
            str = convertCharsToStrings(fileread(html));
            headStr = extractBetween(str, '<style type="text/css">', '</style>');
            styleStr = regexprep(regexprep(headStr, '.rtcContent { padding: 30px; } ',''), '[\n\r]+',' ');
            styleScript = sprintf("<script type=\""text/javascript\"">var css = '%s'; var head = document.head || document.getElementsByTagName('head')[0], style = document.createElement('style'); head.appendChild(style); style.type = 'text/css'; if (style.styleSheet){ style.styleSheet.cssText = css; } else { style.appendChild(document.createTextNode(css)); }</script>", strrep(styleStr, '''', ''));
            treeStr = regexp(str,'((?<=<body>).*(?=<\/body>))','match') + styleScript;
            tree = htmlTree(treeStr);
            imgs = findElement(tree,"div.rtcContent img");
            imgsrcs = getAttribute(imgs, "src");
            date = datetime('now', 'Format', 'yyyy/MM/');
            if ~isempty(imgsrcs)
                for index = 1:length(imgsrcs)
                    imgType = extractBetween(imgsrcs(index), "data:image/", ";base64");
                    newStr = extractAfter(imgsrcs(index), ";base64,");
                    raw = matlab.net.base64decode(newStr);
                    if strcmp(imgType, "jpeg") == 1
                        imgFile = string(mlxname) + "_" + string(index) + ".jpg";
                    else
                        imgFile = string(mlxname) + "_" + string(index) + ".png";
                    end
                    imgName = string(mlxname) + "_" + string(index) + ".png";
                    imgUrl = string(blog_url) + "wp-content/uploads/" + string(date) + string(imgName);
                    treeStr = strrep(treeStr, imgsrcs(index), imgUrl);
                    % decode base64 to images
                    fid = fopen(imgFile, 'wb');
                    fwrite(fid, raw);
                    fclose(fid);
                end
                f=dir('*.jpg');
                fil={f.name};
                for k=1:numel(fil)
                    file = fil{k};
                    new_file = strrep(file,'.jpg','.png');
                    im = imread(file);
                    imwrite(im,new_file);
                    delete(file);
                end
            end

            fileid = fopen("article_body.html", 'wb');
            fwrite(fileid, treeStr, 'char');
            fclose(fileid);
            fprintf('Images convert completed. \n');
        end

        function [stats, output] = clientRequest(app, cmd, env)
            prodstr = "prod";
            if strcmp(prodstr, env)
                [stats, output] = system(cmd);
            else
                [stats, output] = deal(200, 'success');
            end
        end

        function delOldImages(app, loc, auth, token, env)
            imgKey = dir("imgKey.mat");
            if ~isempty(imgKey)
                load("imgKey.mat");
                imgs = dir("*.png");
                for index = 1:length(imgs)
                    imgName = string(imgs(index).name);
                    if isKey(M, imgName)
                        imgId = M(imgName);
                        delEndPoint = string(loc) + 'wp-json/wp/v2/media/' + string(imgId) + "?force=true";
                        delcmd = sprintf('curl --location --request DELETE "%s" --header "Authorization: %s %s"', delEndPoint, auth, token);
                        [stats, output] = clientRequest(app, delcmd, env);
                        if contains(output, "incorrect_password")
                            fprintf('Sorry, your WordPress password is not correct in token \n');
                            app.ErrorLabel.Text = "Sorry, your WordPress password is not correct in token";
                        elseif contains(output, "error")
                            fprintf('Sorry, there are errors in your WordPress \n');
                            app.ErrorLabel.Text = "Sorry, there are errors in your WordPress";
                        else
                            delete(imgName);
                            fprintf('Previous image is deleted in WordPress \n');
                        end
                    end
                end
                clear M;
                delete imgKey.mat;
            end
        end

        function sendImgToWp(app, loc, auth, token, env)
            imgs = dir("*.png");
            if ~isempty(imgs)
                date = datetime('now', 'Format', 'yyyy/MM/');
                M = containers.Map({'imgName'}, {'id'});
                bodyHtml = dir("article_body.html").name;
                str = convertCharsToStrings(fileread(bodyHtml));
                for index = 1:length(imgs)
                    imgName = string(imgs(index).name);
                    imgDir = string(pwd) + filesep + imgName;
                    disp(imgDir);
                    endPoint = string(loc) + 'wp-json/wp/v2/media/';
                    cmd = sprintf('curl --location --request POST "%s" --header "Content-Disposition: attachment;filename=%s" --header "Authorization: %s %s" --header "Content-Type: image/png" --data-binary "@%s"', endPoint, imgName, auth, token, imgDir);
                    disp(cmd);
                    [~,cmdout] = clientRequest(app, cmd, env);
                    disp(cmdout);
                    if contains(cmdout, "incorrect_password")
                        fprintf('Sorry, your WordPress password is not correct in token \n');
                        app.ErrorLabel.Text = "Sorry, your WordPress password is not correct in token";
                    elseif contains(cmdout, "error", "IgnoreCase", true)
                        fprintf('Sorry, there are errors in your WordPress \n');
                        app.ErrorLabel.Text = "Sorry, there are errors in your WordPress";
                    else
                        if contains(cmdout, '"id":')
                            imgId = string(extractBetween(cmdout, '"id":', ',"date"'));
                            disp(imgId);
                            imgUrl = string(loc) + "wp-content/uploads/" + string(date) + string(imgName);
                            disp(imgUrl);
                            imgNewUrl = string(extractBetween(cmdout, ',"raw":"', '"},"modified"'));
                            disp(imgNewUrl);
                            imgNewUrl = erase(imgNewUrl, "\");
                            str = strrep(str, imgUrl, imgNewUrl);
                            M(imgName) = string(imgId);
                        else
                            imgUrl = string(loc) + 'wp-json/wp/v2/media?search=' + string(imgName);
                            disp(imgUrl);
                            uploadedImg = webread(imgUrl);
                            if ~isequaln(uploadedImg, [])
                                disp(uploadedImg);
                                imgId = uploadedImg.id;
                                disp(imgId);
                                imgNewUrl = uploadedImg.source_url;
                                imgUrl = string(loc) + "wp-content/uploads/" + string(date) + string(imgName);
                                str = strrep(str, imgUrl, imgNewUrl);
                                M(imgName) = string(imgId);
                            else
                                fprintf('Image "%s" upload failed, please upload image in WordPress directly. \n', imgName);
                                imgId = datetime('now', 'Format', 'yyyyMMdd');
                                imgNewUrl = string(loc) + 'files/' + string(date) + string(imgName);
                                imgUrl = string(loc) + "wp-content/uploads/" + string(date) + string(imgName);
                                str = strrep(str, imgUrl, imgNewUrl);
                                M(imgName) = string(imgId);
                            end
                        end
                    end
                end
                fileid = fopen("article_body.html", 'wb');
                fwrite(fileid, str, 'char');
                fclose(fileid);
                save imgKey.mat M;
                fprintf('Images are uploaded to WordPress. \n');
            end
        end

        function delOldMlx(app, path, loc, auth, token, env)
            mlxKey = dir("mlxKey.mat");
            if ~isempty(mlxKey)
                load("mlxKey.mat");
                mlxs = dir(string(path) + filesep + "*.mlx");
                for index = 1:length(mlxs)
                    mlxName = string(mlxs(index).name);
                    if isKey(allMlx, mlxName)
                        mlxId = allMlx(mlxName);
                        delEndPoint = string(loc) + 'wp-json/wp/v2/media/' + string(mlxId) + "?force=true";
                        delcmd = sprintf('curl --location --request DELETE "%s" --header "Authorization: %s %s"', delEndPoint, auth, token);
                        [stats, output] = clientRequest(app, delcmd, env);
                        if contains(output, "incorrect_password")
                            fprintf('Sorry, your WordPress password is not correct in token \n');
                            app.ErrorLabel.Text = "Sorry, your WordPress password is not correct in token";
                        elseif contains(output, "error")
                            fprintf('Sorry, there are errors in your WordPress \n');
                            app.ErrorLabel.Text = "Sorry, there are errors in your WordPress";
                        else
                            fprintf('Previous Live Script is deleted in WordPress \n');
                        end
                    end
                end
                clear allMlx;
                delete mlxKey.mat;
            end
        end

        function sendMlxToWp(app, path, loc, auth, token, env)
            mlxname = app.SelectLiveScriptDropDown.Value;
            mlx = dir(string(path) + filesep + string(mlxname));
            if ~isempty(mlx)
                allMlx = containers.Map({'mlxName'}, {'id'});
                mlxName = mlx.name;
                mlxDir = string(path) + filesep + mlxName;
                disp(mlxDir);
                endPoint = string(loc) + 'wp-json/wp/v2/media/';
                cmd = sprintf('curl --location --silent --request POST "%s" --header "Content-Disposition: attachment;filename=%s" --header "Authorization: %s %s" --header "Content-Type: application/octet-stream" --data-binary "@%s"', endPoint, mlxName, auth, token, mlxDir);
                disp(cmd);
                [~,cmdout] = clientRequest(app, cmd, env);
                disp(cmdout);
                if contains(cmdout, "incorrect_password")
                    fprintf('Sorry, your WordPress password is not correct in token \n');
                    app.ErrorLabel.Text = "Sorry, your WordPress password is not correct in token";
                elseif contains(cmdout, "error", "IgnoreCase", true)
                    fprintf('Sorry, there are errors in your WordPress \n');
                    app.ErrorLabel.Text = "Sorry, there are errors in your WordPress";
                else
                    if contains(cmdout, '"id":')
                        % cmdout is JSON
                        json = jsondecode(cmdout);
                        disp(json);
                        mlxUrl = json.source_url;
                        mlxId = json.id;
                    else
                        mlxUrl = string(loc) + 'wp-json/wp/v2/media?search=' + string(mlxName);
                        disp(mlxUrl);
                        uploadedMlx = webread(mlxUrl);
                        if ~isequaln(uploadedMlx, [])
                            disp(uploadedMlx);
                            mlxId = uploadedMlx.id;
                            disp(mlxId);
                            mlxUrl = uploadedMlx.source_url;
                        else
                            date = datetime('now', 'Format', 'yyyy/MM/');
                            fprintf('Live Script "%s" upload failed, please upload Live Script in WordPress directly. \n', mlxName);
                            mlxId = datetime('now', 'Format', 'yyyyMMdd');
                            mlxUrl = string(loc) + 'wp-content/uploads/' + string(date) + string(mlxName);
                        end
                    end

                    bodyHtml = dir("article_body.html").name;
                    downLoadSetting = app.EnableDownLoadCheckBox.Value;
                    if downLoadSetting == 1
                        mlxButton = sprintf('<a href="%s"><button class="btn btn-sm btn_color_blue pull-right add_margin_10">Download Live Script</button></a>', mlxUrl);
                        str = convertCharsToStrings(fileread(bodyHtml)) + mlxButton;
                    else
                        str = convertCharsToStrings(fileread(bodyHtml));
                    end
                    allMlx(mlxName) = string(mlxId);
                    save mlxKey.mat allMlx;
                    fileid = fopen("article_body.html", 'wb');
                    fwrite(fileid, str, 'char');
                    fclose(fileid);
                end
                fprintf('Live script is uploaded to WordPress. \n');
            end
        end

        function postArticle(app, title, loc, auth, token)
            html = dir("article_body.html").name;
            postInfo = dir("post_info.mat");
            if ~isempty(html)
                if ~isempty(postInfo)
                    load post_info.mat;
                    endPoint = string(loc) + 'wp-json/wp/v2/posts/' + string(postId);
                else
                    endPoint = string(loc) + 'wp-json/wp/v2/posts/';
                end
                str = convertCharsToStrings(fileread(string(html)));
                content = regexprep(str, '\n<br>*\n<!--(.*?)-->','');
                auth = sprintf('%s %s', auth, token);
                options = weboptions('HeaderFields',{'Authorization' auth});
                data = struct('title',title,'status', 'draft', 'content', content);
                response = webwrite(endPoint,data,options);
                postId = response.id;
                postLink = response.link;
                editLink = string(loc) + 'wp-admin/post.php?post=' + postId + '&action=edit';
                postTitle = response.title.raw;
                save post_info.mat postId postLink postTitle;
                fprintf('SUCCESS! Please see your blog article here - <a href="%s">%s</a> \n', postLink, response.title.raw);
                web(postLink);
                web(editLink);
            end
        end
    end

    methods(Static)

        function [appDir] = getAppDirectory()
            strPath = which('wp_publisher');
            [appDir,~,~] = fileparts(strPath);
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appDir = wp_publisher.getAppDirectory();
            if isfile(appDir + "bloginfo.mat")
                load(appDir + "bloginfo.mat", "site", "token", "username", "password", "auth", "toolpath");
                if isempty(token) || isempty(site) || isempty(token)
                    app.TabGroup.SelectedTab = app.SettingsTab;
                    app.BlogInfoActionLabel.Text = "Please input your blog information";
                else
                    app.TabGroup.SelectedTab = app.PublishpostTab;
                    app.BlogSiteEditField.Value = site;
                    app.UsernameField.Value = username;
                    app.PasswordField.Value = "******";
                    if (~isempty(toolpath))
                        app.PathField.Value = toolpath;
                    end
                    if (auth == 1)
                        app.JwtButton.Value = true;
                    elseif (auth == 2)
                        app.BasicButton.Value = true;
                    end
                end
                app.BlogInfoActionLabel.Text = "Update your WordPress blog information";
            else
                app.TabGroup.SelectedTab = app.SettingsTab;
                app.BlogInfoActionLabel.Text = "Input your WordPress blog information below";
            end

            if nargin < 2
                file = "all";
            else
                if ~contains(filename, ".mlx")
                    file = filename + ".mlx";
                else
                    file = filename;
                end
            end

            app.mlxDropdown(file);
        end

        % Button pushed function: SaveButton
        function submitBlogInfo(app, event)
            appDir = wp_publisher.getAppDirectory();
            env = 'prod';

            if (isempty(app.BlogSiteEditField.Value))
                warning 'Site info is empty'
            elseif (isempty(app.UsernameField.Value))
                warning 'Username is empty'
            elseif (isempty(app.PasswordField.Value))
                warning 'Password is empty'
            else
                site     = app.BlogSiteEditField.Value;
                username = app.UsernameField.Value;
                password = app.PasswordField.Value;
                toolpath = app.PathField.Value;
                if app.JwtButton.Value
                    auth = 1;
                    if site(end) == "/"
                        tokenEndPoint = string(site) + 'wp-json/jwt-auth/v1/token';
                    else
                        tokenEndPoint = string(site) + '/wp-json/jwt-auth/v1/token';
                    end
                    tokenRequestCmd = sprintf('curl -H "Accept: application/json" -s --location --request POST "%s?username=%s&password=%s"', tokenEndPoint, username, password);
                    [~, output] = clientRequest(app, tokenRequestCmd, env);

                    if isempty(output)
                        fprintf('The server has returned an invalid response. Please ensure the URL for your blog is accurate \n');
                    else
                        result = jsondecode(output);
                        if isfield(result, 'token')
                            fullpath = string(appDir) + "bloginfo.mat";
                            token = result.token;
                            save(fullpath, "site", "token", "username", "password", "auth", "toolpath");
                            app.BlogInfoActionLabel.Text = "Your blog info is saved";
                        else
                            resData = result.data.status;
                            fprintf('Error Code: %d \nDetails: %s', resData, result.message);
                        end
                    end
                    
                elseif app.BasicButton.Value
                    auth = 2;
                    pdstring = sprintf("%s:%s", username, password);
                    token = matlab.net.base64encode(pdstring);
                    fullpath = string(appDir) + "bloginfo.mat";
                    save(fullpath, "site", "token", "username", "password", "auth", "toolpath");
                    app.BlogInfoActionLabel.Text = "Your blog info is saved";
                end
            end
        end

        % Value changed function: SelectLiveScriptDropDown
        function ScriptSelector(app, event)
            value = app.SelectLiveScriptDropDown.Value;
            mlxname = erase(value, ".mlx");
            customizedPath = app.checkPath();
            newpath = customizedPath + mlxname;
            if exist(newpath, 'dir') && isfile(newpath + "/post_info.mat")
                load(fullfile(newpath,"/post_info.mat"));
                app.AppLabel.Text = "Edit existing blog article";
                delete(newpath + "/*.html");
            else
                app.AppLabel.Text = "Post new blog article";
            end
        end

        % Button pushed function: PublishdraftButton
        function PublishdraftButtonPushed(app, event)
            currentpath = pwd;
            app.mlxTohtml();
            strPath = which('wp_publisher');
            [appDir,~,~] = fileparts(strPath);
            if isfile(appDir + "bloginfo.mat")
                load(appDir + "bloginfo.mat", 'site', 'token', 'auth');
                if auth == 1
                    authway = "Bearer";
                elseif auth == 2
                    authway = "Basic";
                end
                if ~isempty(token) && ~isempty(site)
                    mlx = app.SelectLiveScriptDropDown.Value;
                    mlxname = erase(mlx, ".mlx");
                    mlxhtml = string(dir(mlxname + "*.html").name);
                    loc = site;
                    env = "prod";
                    app.delOldImages(loc, authway, token, env);
                    app.delOldMlx(currentpath, loc, authway, token, env);
                    app.removeMlxTitle(mlxhtml);
                    app.getAllLatex("article_body.html");
                    app.getAllEquation("article_body.html");
                    app.getImgToFile(mlxname, "article_body.html", string(loc));
                    app.sendImgToWp(loc, authway, token, env);
                    app.sendMlxToWp(currentpath, loc, authway, token, env);
                    app.replaceEquation(mlxhtml, "article_body.html");

                    title = app.getPostTitle(mlxhtml);

                    app.postArticle(title, loc, authway, token);
                    cd(currentpath);
                    app.AppLabel.Text = "Draft is submitted to your WordPress";
                else
                    fprintf('Sorry, your blog information is incomplete \n');
                    app.ErrorLabel.Text = "Sorry, your blog information is incomplete";
                    app.AppLabel.Text = "Draft cannot be posted, see error message below";
                end
            else
                fprintf('Sorry, there is no blog info in your workspace \n');
                app.ErrorLabel.Text = "Sorry, there is no blog info in your workspace";
                app.TabGroup.SelectedTab = app.SettingsTab;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WordPressPublisher = uifigure('Visible', 'off');
            app.WordPressPublisher.Name = 'MATLAB App';
            app.ErrorLabel = uilabel(app.WordPressPublisher);
            app.ErrorLabel.Text = '';
            app.TabGroup = uitabgroup(app.WordPressPublisher);
            app.PublishpostTab = uitab(app.TabGroup);
            app.PublishpostTab.Title = 'Publish post';
            app.SelectLiveScript = uilabel(app.PublishpostTab);
            app.SelectLiveScript.Text = 'Select Live Script';
            app.SelectLiveScriptDropDown = uidropdown(app.PublishpostTab);
            app.SelectLiveScriptDropDown.Items = {};
            app.SelectLiveScriptDropDown.ValueChangedFcn = createCallbackFcn(app, @ScriptSelector, true);
            app.SelectLiveScriptDropDown.Value = {};
            app.AppLabel = uilabel(app.PublishpostTab);
            app.AppLabel.Text = '';
            app.PublishdraftButton = uibutton(app.PublishpostTab, 'push');
            app.PublishdraftButton.ButtonPushedFcn = createCallbackFcn(app, @PublishdraftButtonPushed, true);
            app.PublishdraftButton.Text = 'Publish draft';
            app.EnableDownLoadCheckBox = uicheckbox(app.PublishpostTab);
            app.EnableDownLoadCheckBox.Text = 'Allow your readers to download your source Live Script';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SaveButton = uibutton(app.SettingsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @submitBlogInfo, true);
            app.SaveButton.Text = 'Save';
            app.BlogSiteEditField = uieditfield(app.SettingsTab, 'text');
            app.BlogSiteEditField.Placeholder = 'https://www.myblog.com/';
            app.WordPressURLLabel = uilabel(app.SettingsTab);
            app.WordPressURLLabel.Text = 'WordPress URL';
            app.UsernameField = uieditfield(app.SettingsTab, 'text');
            app.UsernameField.Placeholder = 'username';
            app.WordPressusernameLabel = uilabel(app.SettingsTab);
            app.WordPressusernameLabel.Text = 'WordPress username';
            app.PasswordField = uieditfield(app.SettingsTab, 'text');
            app.PasswordField.Placeholder = 'password';
            app.WordPresspasswordLabel = uilabel(app.SettingsTab);
            app.WordPresspasswordLabel.Text = 'WordPress password';
            app.BlogInfoActionLabel = uilabel(app.SettingsTab);
            app.BlogInfoActionLabel.Text = '';
            app.WpAuthButtonGroup = uibuttongroup(app.SettingsTab);
            app.WpAuthButtonGroup.Title = 'WordPress API authentication ';
            app.JwtButton = uiradiobutton(app.WpAuthButtonGroup);
            app.JwtButton.Text = 'JWT Authentication';
            app.JwtButton.Value = true;
            app.BasicButton = uiradiobutton(app.WpAuthButtonGroup);
            app.BasicButton.Text = 'WP-API Basic-Auth';
            app.PathField = uieditfield(app.SettingsTab, 'text');
            app.PathField.Placeholder = 'Absolute path';
            app.OutputpathLabel = uilabel(app.SettingsTab);
            app.OutputpathLabel.Text = 'Output path (optional)';
            % Show the figure after all components are created
            app.WordPressPublisher.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = wp_publisher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WordPressPublisher)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WordPressPublisher)
        end
    end
end